classdef stagVer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        outputPanel                 matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        ErrorLabel                  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        GridLayout9                 matlab.ui.container.GridLayout
        GridLayout8                 matlab.ui.container.GridLayout
        SaveAsButton                matlab.ui.control.Button
        GridLayout7                 matlab.ui.container.GridLayout
        ResetButton                 matlab.ui.control.Button
        Label_4                     matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        EncodeTab                   matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        encodeErrorLabel            matlab.ui.control.Label
        EncodeButton                matlab.ui.control.Button
        SecretmessageLabel          matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        TypeyoursecretmessageLabel  matlab.ui.control.Label
        ChooseFileButton            matlab.ui.control.Button
        filenameLabel               matlab.ui.control.Label
        SelectedFileLabel           matlab.ui.control.Label
        ChooseasoundfiletocoverthesecretmessagewithLabel  matlab.ui.control.Label
        DecodeTab                   matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        decodeErrorLabel            matlab.ui.control.Label
        ThespectrogrammightnotplotimmediatelyPleasewaitLabel  matlab.ui.control.Label
        ChoosetheencodedsoundfileyouwanttodecodeLabel  matlab.ui.control.Label
        SelectedFileLabel_2         matlab.ui.control.Label
        filenameLabel_2             matlab.ui.control.Label
        ChooseFileButton_2          matlab.ui.control.Button
        DecodeButton                matlab.ui.control.Button
        DoyouwishtoencodeordecodeafilePanel  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        Switch                      matlab.ui.control.Switch
    end

    
    properties (Access = public)
        coverAudioPathName % Description
    end
    
    properties (Access = public)
        graphFull = false; % Description
    end
    
    properties (Access = public)
        encodedAudio % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
%app.DecodeButton.Enable = "off";
app.decodeErrorLabel.Text = "";
drawnow;

ax = app.UIAxes;
Fs = 44100;

app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = true;
drawnow;
try
fid = fopen(app.coverAudioPathName);
[y,Fs] = audioread(app.coverAudioPathName);
fclose(fid);
catch
    app.decodeErrorLabel.Text = "ERROR. You must choose a .WAV file to decode.";
    app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
    return
end
y_mono_L = y(:,1);
y_mono_R = y(:,2);
N = length(y_mono_L);
t = (0:N-1)/Fs;

ax.CLim = [-20 -18];
[S,F,T] = pspectrum(y_mono_L,Fs,'spectrogram', ...
    'FrequencyLimits',[18900 20100],'TimeResolution',0.05,'Leakage',0.001);
imagesc(app.UIAxes, T, flipud(F), rot90(log(abs(S')))); %plot the log spectrum
set(app.UIAxes,'YDir', 'normal'); % flip the Y Axis
ax.Interactions = [zoomInteraction('Dimensions','x') panInteraction('Dimensions','x')];
ax.Toolbar.Visible = 'off';

app.outputPanel.Visible = true;
app.graphFull = true;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(value == "< Decode")
                app.TabGroup.SelectedTab = app.DecodeTab;
                app.outputPanel.Title = '3): View the secret message.';
                app.SaveAsButton.Visible = false;
                app.coverAudioPathName = "";
                app.filenameLabel.Text = "";
                app.filenameLabel_2.Text = "";
                app.EditField.Value = "";
                if app.graphFull == true
                        app.UIAxes.Children.XData = [];
                        app.UIAxes.Children.YData = [];
                        app.outputPanel.Visible = false;
                        app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
                        app.ErrorLabel.Text = "";
                end
                app.outputPanel.Visible = false;
                app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
                app.ErrorLabel.Text = "";
                app.graphFull = false;
            end
            if(value == "Encode >")
                app.TabGroup.SelectedTab = app.EncodeTab;
                app.outputPanel.Title = '4): Save the encoded file to your computer.';
                app.SaveAsButton.Visible = true;
                app.coverAudioPathName = "";
                app.filenameLabel.Text = "";
                app.filenameLabel_2.Text = "";
                app.EditField.Value = "";
                if app.graphFull == true
                        app.UIAxes.Children.XData = [];
                        app.UIAxes.Children.YData = [];
                        app.outputPanel.Visible = false;
                        app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
                        app.ErrorLabel.Text = "";
                end
                app.outputPanel.Visible = false;
                app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
                app.ErrorLabel.Text = "";
                app.graphFull = false;
            end
        end

        % Button down function: EncodeTab
        function EncodeTabButtonDown(app, event)
            if (app.Switch.Value == "< Decode")
                app.Switch.Value = "Encode >";
                app.outputPanel.Title = '4): Save the encoded file to your computer.';
                app.SaveAsButton.Visible = true;
                app.coverAudioPathName = "";
                app.filenameLabel.Text = "";
                app.filenameLabel_2.Text = "";
                app.EditField.Value = "";
                    if app.graphFull == true
                        app.UIAxes.Children.XData = [];
                        app.UIAxes.Children.YData = [];
                        app.outputPanel.Visible = false;
                        app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
                        app.ErrorLabel.Text = "";
                    end
                app.outputPanel.Visible = false;
                app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
                app.ErrorLabel.Text = "";
                app.graphFull = false;
            end
        end

        % Button down function: DecodeTab
        function DecodeTabButtonDown(app, event)
            if (app.Switch.Value == "Encode >")
                app.Switch.Value = "< Decode";
                app.outputPanel.Title = '3): View the secret message.';
                app.SaveAsButton.Visible = false;
                app.coverAudioPathName = "";
                app.filenameLabel.Text = "";
                app.filenameLabel_2.Text = "";
                app.EditField.Value = "";
                    if app.graphFull == true
                        app.UIAxes.Children.XData = [];
                        app.UIAxes.Children.YData = [];
                        app.outputPanel.Visible = false;
                        app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
                        app.ErrorLabel.Text = "";
                    end
                app.outputPanel.Visible = false;
                app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
                app.ErrorLabel.Text = "";
                app.graphFull = false;
            end
        end

        % Button pushed function: ChooseFileButton
        function ChooseFileButtonPushed(app, event)
            [filename, pathname] = uigetfile ({'*.wav'}, 'Select File');
                if (~ischar(filename))
                    return;    %user cancel
                end
            app.coverAudioPathName = fullfile(pathname, filename);
            app.filenameLabel.Text = filename;
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
app.EncodeButton.Enable = 'off';
app.encodeErrorLabel.Text = "";
drawnow;
            
ax = app.UIAxes;
Fs = 44100;
try
messageText = app.EditField.Value;
catch
    app.encodeErrorLabel.Text = "ERROR. Message text field cannot be empty.";
    app.EditField.Value = "";
    app.SaveAsButton.Visible = false;
    app.EncodeButton.Enable = 'on';
    return
end
[s,Fs] = audioread('LetterTemplates/letter_S_3.wav');
s = s(:,1);
    try
        
            %latest change -- add common character templates 
                if(messageText(1) == '.')
                    [s,Fs] = audioread(strcat('LetterTemplates/letter_._3.wav'));
                    s = s(:,1);
                elseif(messageText(1) == ',')
                    [s,Fs] = audioread(strcat('LetterTemplates/letter_,_3.wav'));
                    s = s(:,1);
                elseif(messageText(1) == '?')
                    [s,Fs] = audioread(strcat('LetterTemplates/letter_question_3.wav'));
                    s = s(:,1);
                else
                    [s,Fs] = audioread(strcat('LetterTemplates/letter_', messageText(1), '_3.wav'));
                    s = s(:,1);
                end
            %end of changes

        for i = 2:length(messageText)
                if (messageText(i) == ' ')
                    x1 = zeros(22050);
                    x1 = x1(:,1);
                    s = [s;x1];
                    s = s(:,1);
                    continue;
                end
                
            %latest change -- add common character templates 
                if(messageText(i) == '.')
                    [x1,Fs] = audioread(strcat('LetterTemplates/letter_._3.wav'));
                    x1 = x1(:,1);
                    s = [s;x1];
                    s = s(:,1);
                elseif(messageText(i) == ',')
                    [x1,Fs] = audioread(strcat('LetterTemplates/letter_,_3.wav'));
                    x1 = x1(:,1);
                    s = [s;x1];
                    s = s(:,1);
                elseif(messageText(i) == '?')
                    [x1,Fs] = audioread(strcat('LetterTemplates/letter_question_3.wav'));
                    x1 = x1(:,1);
                    s = [s;x1];
                    s = s(:,1);
                else
                    [x1,Fs] = audioread(strcat('LetterTemplates/letter_', messageText(i), '_3.wav'));
                    x1 = x1(:,1);
                    s = [s;x1];
                    s = s(:,1);
                end
            %end of changes
        end
        app.SaveAsButton.Visible = true;
        %app.SaveAsButton.Enable = 'on';
    catch
        app.encodeErrorLabel.Text = "ERROR. Text must be capital alphanumeric.";
        app.EditField.Value = "";
        app.SaveAsButton.Visible = false;
        app.EncodeButton.Enable = 'on';
        return
        %app.SaveAsButton.Enable = 'off';
    end
try
fid = fopen(app.coverAudioPathName);
[y,Fs] = audioread(app.coverAudioPathName);
fclose(fid);
catch
    app.encodeErrorLabel.Text = "ERROR. You must choose a .WAV file.";
    app.EditField.Value = "";
    app.SaveAsButton.Visible = false;
    app.EncodeButton.Enable = 'on';
    return
end

y_mono_L = y(:,1);
y_mono_R = y(:,2);
N = length(y_mono_L);
t = (0:N-1)/Fs;
s_paddedl = padarray(s,length(y_mono_L)-length(s),0,'post');
s_paddedr = padarray(s,length(y_mono_R)-length(s),0,'post');
z = s_paddedl + y_mono_L;
z1 = s_paddedr + y_mono_R;

trimmed = z(1:length(s));
t_trimmed = t(1:length(trimmed));

ax.CLim = [-20 -18];
[S,F,T] = pspectrum(trimmed,Fs,'spectrogram', ...
    'FrequencyLimits',[18900 20100],'TimeResolution',0.05,'Leakage',0.001);
imagesc(app.UIAxes, T, flipud(F), rot90(log(abs(S')))); %plot the log spectrum
set(app.UIAxes,'YDir', 'normal'); % flip the Y Axis
ax.Interactions = [zoomInteraction('Dimensions','x') panInteraction('Dimensions','x')];
ax.Toolbar.Visible = 'off';
stereo_mtx = [z(:), z1(:)];

app.encodedAudio = stereo_mtx;
app.EncodeButton.Enable = 'on';
drawnow;
app.outputPanel.Visible = true;
app.graphFull = true;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.coverAudioPathName = "";
            app.filenameLabel.Text = "";
            app.filenameLabel_2.Text = "";
            app.EditField.Value = "";
                if app.graphFull == true
                    app.UIAxes.Children.XData = [];
                    app.UIAxes.Children.YData = [];
                    app.outputPanel.Visible = false;
                    app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
                    app.Switch.Value = "Encode >";
                    app.outputPanel.Title = '4): Save the encoded file to your computer.';
                    app.TabGroup.SelectedTab = app.EncodeTab;
                    app.ErrorLabel.Text = "";
                end
            app.outputPanel.Visible = false;
            app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = false;
            app.Switch.Value = "Encode >";
            app.outputPanel.Title = '4): Save the encoded file to your computer.';
            app.TabGroup.SelectedTab = app.EncodeTab;
            app.ErrorLabel.Text = "";
            app.decodeErrorLabel.Text = "";
            app.graphFull = false;
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            [file, folder] = uiputfile('*.wav');
                if ~isequal(file, 0)  % User did not press Cancel:
                    audiowrite(fullfile(folder, file), app.encodedAudio, 44100);
                end
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: ChooseFileButton_2
        function ChooseFileButton_2Pushed(app, event)
            [filename, pathname] = uigetfile ({'*.wav'}, 'Select File');
                if (~ischar(filename))
                    return;    %user cancel
                end
            app.coverAudioPathName = fullfile(pathname, filename);
            app.filenameLabel_2.Text = filename;
            drawnow;
            figure(app.UIFigure)
        end

        % Callback function
        function PlaysoundButtonValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DoyouwishtoencodeordecodeafilePanel = uipanel(app.GridLayout);
            app.DoyouwishtoencodeordecodeafilePanel.Title = '1): Do you wish to encode or decode a file?';
            app.GridLayout2 = uigridlayout(app.DoyouwishtoencodeordecodeafilePanel);
            app.Switch = uiswitch(app.GridLayout2, 'slider');
            app.Switch.Items = {'Encode >', '< Decode'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Encode >';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.EncodeTab = uitab(app.TabGroup);
            app.EncodeTab.Title = 'Encode';
            app.EncodeTab.ButtonDownFcn = createCallbackFcn(app, @EncodeTabButtonDown, true);
            app.GridLayout3 = uigridlayout(app.EncodeTab);
            app.ChooseasoundfiletocoverthesecretmessagewithLabel = uilabel(app.GridLayout3);
            app.ChooseasoundfiletocoverthesecretmessagewithLabel.Text = '2): Choose a sound file to cover the secret message with.';
            app.SelectedFileLabel = uilabel(app.GridLayout3);
            app.SelectedFileLabel.Text = 'Selected File:';
            app.filenameLabel = uilabel(app.GridLayout3);
            app.filenameLabel.Text = '';
            app.ChooseFileButton = uibutton(app.GridLayout3, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButtonPushed, true);
            app.ChooseFileButton.Text = 'Choose File...';
            app.TypeyoursecretmessageLabel = uilabel(app.GridLayout3);
            app.TypeyoursecretmessageLabel.Text = '3): Type your secret message.';
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField.Placeholder = '(must be alphanumeric)';
            app.SecretmessageLabel = uilabel(app.GridLayout3);
            app.SecretmessageLabel.Text = 'Secret message:';
            app.EncodeButton = uibutton(app.GridLayout3, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Text = 'Encode';
            app.encodeErrorLabel = uilabel(app.GridLayout3);
            app.encodeErrorLabel.Text = '';
            app.DecodeTab = uitab(app.TabGroup);
            app.DecodeTab.Title = 'Decode';
            app.DecodeTab.ButtonDownFcn = createCallbackFcn(app, @DecodeTabButtonDown, true);
            app.GridLayout4 = uigridlayout(app.DecodeTab);
            app.DecodeButton = uibutton(app.GridLayout4, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            app.ChooseFileButton_2 = uibutton(app.GridLayout4, 'push');
            app.ChooseFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseFileButton_2Pushed, true);
            app.ChooseFileButton_2.Text = 'Choose File...';
            app.filenameLabel_2 = uilabel(app.GridLayout4);
            app.filenameLabel_2.Text = '';
            app.SelectedFileLabel_2 = uilabel(app.GridLayout4);
            app.SelectedFileLabel_2.Text = 'Selected File:';
            app.ChoosetheencodedsoundfileyouwanttodecodeLabel = uilabel(app.GridLayout4);
            app.ChoosetheencodedsoundfileyouwanttodecodeLabel.Text = '2): Choose the encoded sound file you want to decode.';
            app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel = uilabel(app.GridLayout4);
            app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Visible = 'off';
            app.ThespectrogrammightnotplotimmediatelyPleasewaitLabel.Text = 'The spectrogram might not plot immediately. Please wait.';
            app.decodeErrorLabel = uilabel(app.GridLayout4);
            app.decodeErrorLabel.Text = '';
            app.outputPanel = uipanel(app.GridLayout);
            app.outputPanel.Title = '4): Save the encoded file to your computer.';
            app.outputPanel.Visible = 'off';
            app.GridLayout5 = uigridlayout(app.outputPanel);
            app.UIAxes = uiaxes(app.GridLayout5);
            title(app.UIAxes, 'Spectrogram of Encoded File')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Frequency')
            app.UIAxes.YLim = [18900 20100];
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.YTickLabel = {'19k'; '19.5k'; '20k'};
            app.UIAxes.ZTick = [];
            app.Panel = uipanel(app.GridLayout5);
            app.GridLayout6 = uigridlayout(app.Panel);
            app.Label_4 = uilabel(app.GridLayout6);
            app.Label_4.Text = 'You can drag the graph horizontally to shift the view left and right. Scroll to zoom in/out. If it''s unresponsive, click on the graph once and try again. If you''re having trouble zooming in/out, drag the graph a little bit and try again.';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.ResetButton = uibutton(app.GridLayout7, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.SaveAsButton = uibutton(app.GridLayout8, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Text = 'Save As...';
            app.GridLayout9 = uigridlayout(app.GridLayout6);
            app.ErrorLabel = uilabel(app.GridLayout5);
            app.ErrorLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stagVer2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end